classdef liveSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        R_Slider             matlab.ui.control.Slider
        uLabel               matlab.ui.control.Label
        EditField_R          matlab.ui.control.NumericEditField
        ControlCostRLabel    matlab.ui.control.Label
        EditField_phi_dot    matlab.ui.control.NumericEditField
        QdotphiSlider        matlab.ui.control.Slider
        QdotphiSliderLabel   matlab.ui.control.Label
        EditField_phi        matlab.ui.control.NumericEditField
        QphiSlider           matlab.ui.control.Slider
        QphiSliderLabel      matlab.ui.control.Label
        EditField_theta_dot  matlab.ui.control.NumericEditField
        QdotthetaSlider      matlab.ui.control.Slider
        dotthetaLabel        matlab.ui.control.Label
        EditField_theta      matlab.ui.control.NumericEditField
        QthetaSlider         matlab.ui.control.Slider
        thetaLabel           matlab.ui.control.Label
        StateCostsQLabel     matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        Q_theta = 0;
        Q_theta_dot = 0;
        Q_phi = 0;
        Q_phi_dot = 0;

        R_u = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: QthetaSlider
        function QthetaSliderValueChanged(app, event)
            app.Q_theta = app.QthetaSlider.Value;
            app.EditField_theta.Value = app.Q_theta;
        end

        % Value changed function: EditField_theta
        function EditField_thetaValueChanged(app, event)
            app.Q_theta = app.EditField_theta.Value;
            app.QthetaSlider.Value = app.Q_theta;
        end

        % Value changed function: QdotthetaSlider
        function QdotthetaSliderValueChanged(app, event)
            app.Q_theta_dot = app.QdotthetaSlider.Value;
            app.EditField_theta_dot.Value = app.Q_theta_dot;
        end

        % Value changed function: EditField_theta_dot
        function EditField_theta_dotValueChanged(app, event)
            app.Q_theta_dot = app.EditField_theta_dot.Value;
            app.QdotthetaSlider.Value = app.Q_theta_dot;
        end

        % Value changed function: QphiSlider
        function QphiSliderValueChanged(app, event)
            app.Q_phi = app.QphiSlider.Value;
            app.EditField_phi.Value = app.Q_phi;
        end

        % Value changed function: EditField_phi
        function EditField_phiValueChanged(app, event)
            app.Q_phi = app.EditField_phi.Value;
            app.QphiSlider.Value = app.Q_phi;
        end

        % Value changed function: QdotphiSlider
        function QdotphiSliderValueChanged(app, event)
            app.Q_phi_dot = app.QdotphiSlider.Value;
            app.EditField_phi_dot.Value = app.Q_phi_dot;
        end

        % Value changed function: EditField_phi_dot
        function EditField_phi_dotValueChanged(app, event)
            app.Q_phi_dot = app.EditField_phi_dot.Value;
            app.QdotphiSlider.Value = app.Q_phi_dot;
        end

        % Value changed function: R_Slider
        function R_SliderValueChanged(app, event)
            app.R_u = app.R_Slider.Value;
            app.EditField_R.Value = app.R_u;
        end

        % Value changed function: EditField_R
        function EditField_RValueChanged(app, event)
            app.R_u = app.EditField_R.Value;
            app.R_Slider.Value = app.R_u;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StateCostsQLabel = uilabel(app.LeftPanel);
            app.StateCostsQLabel.Text = 'State Costs ''Q''';
            app.thetaLabel = uilabel(app.LeftPanel);
            app.thetaLabel.Interpreter = 'latex';
            app.thetaLabel.Text = '$Q.\theta$';
            app.QthetaSlider = uislider(app.LeftPanel);
            app.QthetaSlider.ValueChangedFcn = createCallbackFcn(app, @QthetaSliderValueChanged, true);
            app.EditField_theta = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_theta.ValueChangedFcn = createCallbackFcn(app, @EditField_thetaValueChanged, true);
            app.dotthetaLabel = uilabel(app.LeftPanel);
            app.dotthetaLabel.Interpreter = 'latex';
            app.dotthetaLabel.Text = '$Q.\dot{\theta}$';
            app.QdotthetaSlider = uislider(app.LeftPanel);
            app.QdotthetaSlider.ValueChangedFcn = createCallbackFcn(app, @QdotthetaSliderValueChanged, true);
            app.EditField_theta_dot = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_theta_dot.ValueChangedFcn = createCallbackFcn(app, @EditField_theta_dotValueChanged, true);
            app.QphiSliderLabel = uilabel(app.LeftPanel);
            app.QphiSliderLabel.Interpreter = 'latex';
            app.QphiSliderLabel.Text = '$Q.\phi$';
            app.QphiSlider = uislider(app.LeftPanel);
            app.QphiSlider.ValueChangedFcn = createCallbackFcn(app, @QphiSliderValueChanged, true);
            app.EditField_phi = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_phi.ValueChangedFcn = createCallbackFcn(app, @EditField_phiValueChanged, true);
            app.QdotphiSliderLabel = uilabel(app.LeftPanel);
            app.QdotphiSliderLabel.Interpreter = 'latex';
            app.QdotphiSliderLabel.Text = '$Q.\dot{\phi}$';
            app.QdotphiSlider = uislider(app.LeftPanel);
            app.QdotphiSlider.ValueChangedFcn = createCallbackFcn(app, @QdotphiSliderValueChanged, true);
            app.EditField_phi_dot = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_phi_dot.ValueChangedFcn = createCallbackFcn(app, @EditField_phi_dotValueChanged, true);
            app.ControlCostRLabel = uilabel(app.LeftPanel);
            app.ControlCostRLabel.Text = 'Control Cost ''R''';
            app.EditField_R = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_R.ValueChangedFcn = createCallbackFcn(app, @EditField_RValueChanged, true);
            app.uLabel = uilabel(app.LeftPanel);
            app.uLabel.Interpreter = 'latex';
            app.uLabel.Text = '$R.u$';
            app.R_Slider = uislider(app.LeftPanel);
            app.R_Slider.Limits = [0 10000];
            app.R_Slider.ValueChangedFcn = createCallbackFcn(app, @R_SliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = liveSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end